classdef covid_data_analysis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        StatisticsofCovidCasesDeathsUIFigure  matlab.ui.Figure
        AveragedofdaysSlider       matlab.ui.control.Slider
        AveragedofdaysSliderLabel  matlab.ui.control.Label
        StateorRegionListBox       matlab.ui.control.ListBox
        StateorRegionListBoxLabel  matlab.ui.control.Label
        CountryListBox             matlab.ui.control.ListBox
        CountryLabel               matlab.ui.control.Label
        OptionButtonGroup          matlab.ui.container.ButtonGroup
        DailyButton                matlab.ui.control.RadioButton
        CumulativeButton           matlab.ui.control.RadioButton
        DatatoPlotButtonGroup      matlab.ui.container.ButtonGroup
        BothButton                 matlab.ui.control.RadioButton
        DeathsButton               matlab.ui.control.RadioButton
        CasesButton                matlab.ui.control.RadioButton
        UIAxes                     matlab.ui.control.UIAxes
    end


    properties (Access = private)
       data= load("covid_data.mat");
       CountryNames
       CountryNamesRe
       States
       Dates
       CumNumberOfDeath
       NumberOfDeath
       CumNumberOfCases
       NumberOfCases
    end
    
    methods (Access = private)


        function default(app)
           y = {app.data.covid_data{2:339}};
           app.CountryNamesRe= string(y);
         y=unique(y, 'stable');
         app.CountryNames = y;
         y={app.data.covid_data{2:339,2}};
         app.States = string(y);
         
          app.CountryListBox.Items= {'Global',app.CountryNames{1:length(app.CountryNames)}};
        app.CountryListBox.Value= 'Global';
        app.StateorRegionListBox.Items={'All'};
        app.StateorRegionListBox.Value='All';
                y = app.data.covid_data(2:339,3:709);
                app.CumNumberOfCases = cellfun(@(x) x(1), y, 'UniformOutput', false);
                app.CumNumberOfCases= cell2mat(app.CumNumberOfCases);
                app.NumberOfCases= [app.CumNumberOfCases(:, 1), diff(app.CumNumberOfCases, 1, 2)];


                app.CumNumberOfDeath = app.data.covid_data(2:339,3:709);
                app.CumNumberOfDeath = cellfun(@(x) x(2), y, 'UniformOutput', false);
                app.CumNumberOfDeath= cell2mat(app.CumNumberOfDeath);
                app.NumberOfDeath= [app.CumNumberOfDeath(:, 1), diff(app.CumNumberOfDeath, 1, 2)];
                app.Dates = datetime('2020-01-22'):days(1):datetime('2021-12-28');
         
         app.ploot;
        
        end

        function ploot(app)
           %cla(app.UIAxes,'reset');
           yyaxis(app.UIAxes,'right');
               
                
                set(plot(app.UIAxes,app.Dates,[zeros(size(app.Dates))],"Red"),"Visible","off");
                set(app.UIAxes.YAxis(2),"Visible","off");
           yyaxis(app.UIAxes,"left");
           app.UIAxes.YAxis(1).Color = '#1f77b4';
           
            if app.CumulativeButton.Value && app.CasesButton.Value

            if app.CountryListBox.Value == "Global"
                noValueIndices  = find( cellfun(@isempty, app.States));
                y= sum(app.CumNumberOfCases(noValueIndices,:));
                bar(app.UIAxes, app.Dates,movmean( y,app.AveragedofdaysSlider.Value));
                % movmean( ,app.AveragedofdaysSlider.Value)
                app.UIAxes.YLim= [min(y),max(y)];
                app.UIAxes.XLim=[app.Dates(1),app.Dates(end)];
                title(app.UIAxes,"Cumulative Number of Cases Globally("+ app.AveragedofdaysSlider.Value+" days mean)");
            else 
                x=1;
                i=find(contains(app.CountryNamesRe, app.CountryListBox.Value));
                if app.StateorRegionListBox.Value ~= "All"
                    x= find(string(app.StateorRegionListBox.Items)==app.StateorRegionListBox.Value);
                end
                bar(app.UIAxes,app.Dates,movmean( app.CumNumberOfCases(i(x),:),app.AveragedofdaysSlider.Value));
                
                app.UIAxes.YLim= [min(app.CumNumberOfCases(i(x),:)),max(app.CumNumberOfCases(i(x),:))];
                app.UIAxes.XLim=[app.Dates(1),app.Dates(end)];
                if app.StateorRegionListBox.Value == "All"
                title(app.UIAxes,"Cumulative Number of Cases in "+app.CountryListBox.Value+"("+ app.AveragedofdaysSlider.Value+" days mean)");
                else 
                    title(app.UIAxes,"Cumulative Number of Cases in "+app.StateorRegionListBox.Value+"("+ app.AveragedofdaysSlider.Value+" days mean)");
                end                
            end
            %%second case
            elseif app.DailyButton.Value && app.CasesButton.Value
                if app.CountryListBox.Value == "Global"
                noValueIndices  = find( cellfun(@isempty, app.States));
                y= sum(app.NumberOfCases(noValueIndices,:));
                bar(app.UIAxes,app.Dates,movmean(y ,app.AveragedofdaysSlider.Value));
                
                app.UIAxes.YLim= [min(y),max(y)];
                app.UIAxes.XLim=[app.Dates(1),app.Dates(end)];
               title(app.UIAxes,"Daily Number of Cases Globally("+ app.AveragedofdaysSlider.Value+" days mean)");
            else 
                x=1;
                i=find(contains(app.CountryNamesRe, app.CountryListBox.Value));
                if app.StateorRegionListBox.Value ~= "All"
                    x= find(string(app.StateorRegionListBox.Items)==app.StateorRegionListBox.Value);
                end
                bar(app.UIAxes,app.Dates,movmean( app.NumberOfCases(i(x),:),app.AveragedofdaysSlider.Value));
                
                app.UIAxes.YLim= [min(app.NumberOfCases(i(x),:)),max(app.NumberOfCases(i(x),:))];
                app.UIAxes.XLim=[app.Dates(1),app.Dates(end)];
                 if app.StateorRegionListBox.Value == "All"
                title(app.UIAxes,"Daily Number of Cases in "+app.CountryListBox.Value+"("+ app.AveragedofdaysSlider.Value+" days mean)");
                else 
                    title(app.UIAxes,"Daily Number of Cases in "+app.StateorRegionListBox.Value+"("+ app.AveragedofdaysSlider.Value+" days mean)");
                end   
                
                
                end
                %%third case
            elseif  app.CumulativeButton.Value && app.DeathsButton.Value
                app.UIAxes.YAxis(1).Color = 'Red';
                if app.CountryListBox.Value == "Global"
                noValueIndices  = find( cellfun(@isempty, app.States));
                y= sum(app.CumNumberOfDeath(noValueIndices,:));
                plot(app.UIAxes,app.Dates,movmean( y,app.AveragedofdaysSlider.Value),"Red");
                
                app.UIAxes.YLim= [min(y),max(y)];
                app.UIAxes.XLim=[app.Dates(1),app.Dates(end)];
                title(app.UIAxes,"Cumulative Number of Deathes Globally("+ app.AveragedofdaysSlider.Value+" days mean)");
            else 
                x=1;
                i=find(contains(app.CountryNamesRe, app.CountryListBox.Value));
                if app.StateorRegionListBox.Value ~= "All"
                    x= find(string(app.StateorRegionListBox.Items)==app.StateorRegionListBox.Value);
                end
                plot(app.UIAxes,app.Dates,movmean( app.CumNumberOfDeath(i(x),:),app.AveragedofdaysSlider.Value),"Red");
                
                app.UIAxes.YLim= [min(app.CumNumberOfDeath(i(x),:)),max(app.CumNumberOfDeath(i(x),:))+1];
                app.UIAxes.XLim=[app.Dates(1),app.Dates(end)];
                 if app.StateorRegionListBox.Value == "All"
                title(app.UIAxes,"Cumulative Number of Deathes in "+app.CountryListBox.Value+"("+ app.AveragedofdaysSlider.Value+" days mean)");
                else 
                    title(app.UIAxes,"Cumulative Number of Deathes in "+app.StateorRegionListBox.Value+"("+ app.AveragedofdaysSlider.Value+" days mean)");
                end   
                
                end
                %fourthcase
            elseif app.DailyButton.Value && app.DeathsButton.Value
                app.UIAxes.YAxis(1).Color = 'Red';
                    if app.CountryListBox.Value == "Global"
                noValueIndices  = find( cellfun(@isempty, app.States));
                y= sum(app.NumberOfDeath(noValueIndices,:));
                plot(app.UIAxes,app.Dates,movmean(y,app.AveragedofdaysSlider.Value),"Red");
                
                app.UIAxes.YLim= [min(y),max(y)];
                app.UIAxes.XLim=[app.Dates(1),app.Dates(end)];
                title(app.UIAxes,"Daily Number of Deathes Globally("+ app.AveragedofdaysSlider.Value+" days mean)");
            else 
                x=1;
                i=find(contains(app.CountryNamesRe, app.CountryListBox.Value));
                if app.StateorRegionListBox.Value ~= "All"
                    x= find(string(app.StateorRegionListBox.Items)==app.StateorRegionListBox.Value);
                end
                plot(app.UIAxes,app.Dates,movmean(app.NumberOfDeath(i(x),:),app.AveragedofdaysSlider.Value),"Red");
                
                app.UIAxes.YLim= [min(app.NumberOfDeath(i(x),:)),max(app.NumberOfDeath(i(x),:))+1];
                app.UIAxes.XLim=[app.Dates(1),app.Dates(end)];
                
                 if app.StateorRegionListBox.Value == "All"
                title(app.UIAxes,"Daily Number of Deathes in "+app.CountryListBox.Value+"("+ app.AveragedofdaysSlider.Value+" days mean)");
                else 
                    title(app.UIAxes,"Daily Number of Deathes in "+app.StateorRegionListBox.Value+"("+ app.AveragedofdaysSlider.Value+" days mean)");
                end 
                    end
                    %%5th case
                    elseif app.CumulativeButton.Value && app.BothButton.Value
                        set(app.UIAxes.YAxis(2),"Visible","on");
                        if app.CountryListBox.Value == "Global"
                noValueIndices  = find( cellfun(@isempty, app.States));
                y= sum(app.CumNumberOfCases(noValueIndices,:));
                bar(app.UIAxes,app.Dates,movmean(y,app.AveragedofdaysSlider.Value));
                app.UIAxes.YLim= [min(y),max(y)];
                app.UIAxes.XLim=[app.Dates(1),app.Dates(end)];
                yyaxis(app.UIAxes,'right');
               
                plot(app.UIAxes,app.Dates,movmean(sum(app.CumNumberOfDeath(noValueIndices,:)),app.AveragedofdaysSlider.Value),"Red");

                app.UIAxes.YLim= [min(sum(app.CumNumberOfDeath(noValueIndices,:))),max(sum(app.CumNumberOfDeath(noValueIndices,:)))];
                title(app.UIAxes,"Cumulative Number of Cases and Deathes Globally("+ app.AveragedofdaysSlider.Value+" days mean)");
               
                        else 
                x=1;
                i=find(contains(app.CountryNamesRe, app.CountryListBox.Value));
                if app.StateorRegionListBox.Value ~= "All"
                    x= find(string(app.StateorRegionListBox.Items)==app.StateorRegionListBox.Value);
                end
                bar(app.UIAxes,app.Dates,movmean(app.CumNumberOfCases(i(x),:),app.AveragedofdaysSlider.Value));
                
                app.UIAxes.YLim= [min(app.CumNumberOfCases(i(x),:)),max(app.CumNumberOfCases(i(x),:))];
                app.UIAxes.XLim=[app.Dates(1),app.Dates(end)];
                yyaxis(app.UIAxes,'right');
                plot(app.UIAxes,app.Dates,movmean(app.CumNumberOfDeath(i(x),:),app.AveragedofdaysSlider.Value),"Red");
                
                app.UIAxes.YLim= [min(app.CumNumberOfDeath(i(x),:)),max(app.CumNumberOfDeath(i(x),:))+1];
                 if app.StateorRegionListBox.Value == "All"
                title(app.UIAxes,"Cumulative Number of Cases and Deathes in "+app.CountryListBox.Value+"("+ app.AveragedofdaysSlider.Value+" days mean)");
                else 
                    title(app.UIAxes,"Cumulative Number of Cases and Deathes in "+app.StateorRegionListBox.Value+"("+ app.AveragedofdaysSlider.Value+" days mean)");
                end 
                        end 
            else
                set(app.UIAxes.YAxis(2),"Visible","on");
                        if app.CountryListBox.Value == "Global"
                noValueIndices  = find( cellfun(@isempty, app.States));
                y= sum(app.NumberOfCases(noValueIndices,:));
                bar(app.UIAxes,app.Dates,movmean(y,app.AveragedofdaysSlider.Value));
                app.UIAxes.YLim= [min(y),max(y)];
                app.UIAxes.XLim=[app.Dates(1),app.Dates(end)];
                yyaxis(app.UIAxes,'right');
               
                plot(app.UIAxes,app.Dates,movmean(sum(app.NumberOfDeath(noValueIndices,:)),app.AveragedofdaysSlider.Value),"Red");

                app.UIAxes.YLim= [min(sum(app.NumberOfDeath(noValueIndices,:))),max(sum(app.NumberOfDeath(noValueIndices,:)))];
               title(app.UIAxes,"Daily Number of Cases and Deathes Globally("+ app.AveragedofdaysSlider.Value+" days mean)");
                        else 
                x=1;
                i=find(contains(app.CountryNamesRe, app.CountryListBox.Value));
                if app.StateorRegionListBox.Value ~= "All"
                    x= find(string(app.StateorRegionListBox.Items)==app.StateorRegionListBox.Value);
                end
                bar(app.UIAxes,app.Dates,movmean(app.NumberOfCases(i(x),:),app.AveragedofdaysSlider.Value));
                
                app.UIAxes.YLim= [min(app.NumberOfCases(i(x),:)),max(app.NumberOfCases(i(x),:))];
                app.UIAxes.XLim=[app.Dates(1),app.Dates(end)];
                yyaxis(app.UIAxes,'right');
                plot(app.UIAxes,app.Dates,movmean(app.NumberOfDeath(i(x),:),app.AveragedofdaysSlider.Value),"Red");
                
                app.UIAxes.YLim= [min(app.NumberOfDeath(i(x),:)),max(app.NumberOfDeath(i(x),:))+1];
                if app.StateorRegionListBox.Value == "All"
                title(app.UIAxes,"Daily Number of Cases and Deathes in "+app.CountryListBox.Value+"("+ app.AveragedofdaysSlider.Value+" days mean)");
                else 
                    title(app.UIAxes,"Daily Number of Cases and Deathes in "+app.StateorRegionListBox.Value+"("+ app.AveragedofdaysSlider.Value+" days mean)");
                end 
                        end 
            end


        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
         app.default;   


        end

        % Value changed function: CountryListBox
        function CountryListBoxValueChanged(app, event)
                
         if app.CountryListBox.Value~= "Global"
             i= find (app.CountryNamesRe == app.CountryListBox.Value,1);
         % Find the index of the first NaN or empty element
noValueIndices  = find( cellfun(@isempty, app.States));
firstNoValueIndex = min(noValueIndices(noValueIndices > i));

% Extract elements from the beginning up to the first no value element

app.StateorRegionListBox.Items={'All',app.States{i+1:firstNoValueIndex - 1}};
         else 
             app.StateorRegionListBox.Items={'All'};
         end
app.ploot

        end

        % Selection changed function: DatatoPlotButtonGroup
        function DatatoPlotButtonGroupSelectionChanged(app, event)
        app.ploot;
        end

        % Selection changed function: OptionButtonGroup
        function OptionButtonGroupSelectionChanged(app, event)
        app.ploot;
        end

        % Value changed function: StateorRegionListBox
        function StateorRegionListBoxValueChanged(app, event)
           app.ploot;
        end

        % Value changing function: AveragedofdaysSlider
        function AveragedofdaysSliderValueChanging(app, event)
         %%not used
        end

        % Clicked callback: CountryListBox
        function CountryListBoxClicked(app, event)
            app.ploot;
            
        end

        % Value changed function: AveragedofdaysSlider
        function AveragedofdaysSliderValueChanged(app, event)
               app.AveragedofdaysSlider.Value = floor(app.AveragedofdaysSlider.Value);
               app.ploot;
            
        end

        % Clicked callback: StateorRegionListBox
        function StateorRegionListBoxClicked(app, event)
            app.ploot;
            
        end

        % Button down function: DatatoPlotButtonGroup
        function DatatoPlotButtonGroupButtonDown(app, event)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.StatisticsofCovidCasesDeathsUIFigure = uifigure('Visible', 'off');
            app.StatisticsofCovidCasesDeathsUIFigure.Name = 'Statistics of Covid Cases & Deaths';
            app.UIAxes = uiaxes(app.StatisticsofCovidCasesDeathsUIFigure);
            title(app.UIAxes, 'Title')
            app.DatatoPlotButtonGroup = uibuttongroup(app.StatisticsofCovidCasesDeathsUIFigure);
            app.DatatoPlotButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DatatoPlotButtonGroupSelectionChanged, true);
            app.DatatoPlotButtonGroup.Title = 'Data to Plot';
            app.DatatoPlotButtonGroup.ButtonDownFcn = createCallbackFcn(app, @DatatoPlotButtonGroupButtonDown, true);
            app.CasesButton = uiradiobutton(app.DatatoPlotButtonGroup);
            app.CasesButton.Text = 'Cases';
            app.CasesButton.Value = true;
            app.DeathsButton = uiradiobutton(app.DatatoPlotButtonGroup);
            app.DeathsButton.Text = 'Deaths';
            app.BothButton = uiradiobutton(app.DatatoPlotButtonGroup);
            app.BothButton.Text = 'Both';
            app.OptionButtonGroup = uibuttongroup(app.StatisticsofCovidCasesDeathsUIFigure);
            app.OptionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @OptionButtonGroupSelectionChanged, true);
            app.OptionButtonGroup.Title = 'Option';
            app.CumulativeButton = uiradiobutton(app.OptionButtonGroup);
            app.CumulativeButton.Text = 'Cumulative';
            app.CumulativeButton.Value = true;
            app.DailyButton = uiradiobutton(app.OptionButtonGroup);
            app.DailyButton.Text = 'Daily';
            app.CountryLabel = uilabel(app.StatisticsofCovidCasesDeathsUIFigure);
            app.CountryLabel.Text = 'Country:';
            app.CountryListBox = uilistbox(app.StatisticsofCovidCasesDeathsUIFigure);
            app.CountryListBox.Items = {};
            app.CountryListBox.ValueChangedFcn = createCallbackFcn(app, @CountryListBoxValueChanged, true);
            app.CountryListBox.ClickedFcn = createCallbackFcn(app, @CountryListBoxClicked, true);
            app.CountryListBox.Value = {};
            app.StateorRegionListBoxLabel = uilabel(app.StatisticsofCovidCasesDeathsUIFigure);
            app.StateorRegionListBoxLabel.Text = {'State or'; 'Region:'};
            app.StateorRegionListBox = uilistbox(app.StatisticsofCovidCasesDeathsUIFigure);
            app.StateorRegionListBox.Items = {};
            app.StateorRegionListBox.ValueChangedFcn = createCallbackFcn(app, @StateorRegionListBoxValueChanged, true);
            app.StateorRegionListBox.ClickedFcn = createCallbackFcn(app, @StateorRegionListBoxClicked, true);
            app.StateorRegionListBox.Value = {};
            app.AveragedofdaysSliderLabel = uilabel(app.StatisticsofCovidCasesDeathsUIFigure);
            app.AveragedofdaysSliderLabel.Text = {'Averaged'; '# of days'};
            app.AveragedofdaysSlider = uislider(app.StatisticsofCovidCasesDeathsUIFigure);
            app.AveragedofdaysSlider.Limits = [1 15];
            app.AveragedofdaysSlider.MajorTicks = [1 3 5 7 9 11 13 15];
            app.AveragedofdaysSlider.ValueChangedFcn = createCallbackFcn(app, @AveragedofdaysSliderValueChanged, true);
            app.AveragedofdaysSlider.ValueChangingFcn = createCallbackFcn(app, @AveragedofdaysSliderValueChanging, true);
            app.AveragedofdaysSlider.Value = 1;
            % Show the figure after all components are created
            app.StatisticsofCovidCasesDeathsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = covid_data_analysis

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.StatisticsofCovidCasesDeathsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.StatisticsofCovidCasesDeathsUIFigure)
        end
    end
end